classdef Laboratorio1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        fEditField              matlab.ui.control.NumericEditField
        fEditFieldLabel         matlab.ui.control.Label
        eEditField              matlab.ui.control.NumericEditField
        eEditFieldLabel         matlab.ui.control.Label
        Switch_3                matlab.ui.control.Switch
        Label_4                 matlab.ui.control.Label
        Switch                  matlab.ui.control.Switch
        Label_3                 matlab.ui.control.Label
        Switch_2                matlab.ui.control.Switch
        Label_2                 matlab.ui.control.Label
        GraphButton_2           matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        OperationDropDown       matlab.ui.control.DropDown
        OperationDropDownLabel  matlab.ui.control.Label
        dEditField              matlab.ui.control.NumericEditField
        dEditFieldLabel         matlab.ui.control.Label
        SealesySistemasIEN410020223Label  matlab.ui.control.Label
        SignalsgeneratorLabel   matlab.ui.control.Label
        cEditField              matlab.ui.control.NumericEditField
        cEditFieldLabel         matlab.ui.control.Label
        aEditField              matlab.ui.control.NumericEditField
        aEditFieldLabel         matlab.ui.control.Label
        bEditField              matlab.ui.control.NumericEditField
        bEditFieldLabel         matlab.ui.control.Label
        signal                  matlab.ui.control.DropDown
        SealLabel               matlab.ui.control.Label
        GraphButton             matlab.ui.control.Button
        UIAxes6                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y % function
        t % time
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: signal
        function signalValueChanged(app, event)
            s = app.signal.Value;

            if strcmp(s, 'Senoidal')
                app.aEditFieldLabel.Text='w';
                app.bEditFieldLabel.Text='a';
                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditField.Visible=false;
                app.cEditFieldLabel.Visible=false;
                app.Label.Text='You selected:';
            elseif strcmp(s, 'Pulso')
                app.aEditFieldLabel.Text='w';
                app.bEditFieldLabel.Text='f';
                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditFieldLabel.Visible=false;
                app.cEditField.Visible=false;
                app.Label.Text='You selected:';
            elseif strcmp(s, 'Cuadrática')
                app.aEditFieldLabel.Text='a';
                app.bEditFieldLabel.Text='b';

                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditFieldLabel.Visible=true;
                app.Label.Text='You selected:';
            elseif strcmp(s,'Exponencial')
                app.aEditFieldLabel.Text='a';
                app.bEditFieldLabel.Text='b';
                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditField.Visible=false;
                app.Label.Text='You selected:';
            elseif strcmp(s, 'Lineal')
                app.aEditFieldLabel.Text='m';
                app.bEditFieldLabel.Text='b';

                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditField.Visible=false;
                app.Label.Text='You selected:';
            elseif strcmp(s, 'Triangular')
                 app.aEditFieldLabel.Text='X';
                app.cEditFieldLabel.Text="W";
                app.bEditFieldLabel.Text="A";
                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditFieldLabel.Visible=true;
                app.cEditField.Visible=true;
                app.Label.Visible=true;
                app.Label.Text='Digite un número del 0 al 1';
            elseif strcmp(s, 'Cuadrada')
                app.aEditFieldLabel.Text='d';
                app.bEditFieldLabel.Text="w";
                 app.cEditFieldLabel.Text="A";
                app.bEditFieldLabel.Visible=true;
                app.bEditField.Visible=true;
                app.cEditFieldLabel.Visible=true;
                app.cEditField.Visible=true;
                app.Label.Visible=true;
                app.Label.Text='You selected:';
            elseif strcmp(s, 'Secuencia de impulsos')
                app.aEditFieldLabel.Text='w';
                
                app.bEditFieldLabel.Visible=false;
                app.bEditField.Visible=false;
                app.cEditFieldLabel.Visible=false;
                app.cEditField.Visible=false;
                app.Label.Visible=true;
                app.Label.Text='You selected:';
            end
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)

        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            s=app.signal.Value;

            if strcmp(s, 'Senoidal')
                w = app.aEditField.Value;
                a = app.bEditField.Value;
                app.t = 0:1/(2*w):2*pi;
                app.y = a*sin(w*app.t);
                plot(app.UIAxes,app.t,app.y)
            elseif strcmp(s, 'Pulse')
                w = app.aEditField.Value;
                f = app.aEditField.Value;
                app.t = -2*pi:1/(2*f):2*pi;
                app.y = rectpuls(app.t,w);
                plot(app.UIAxes,app.t,app.y)
            elseif strcmp(s, 'Quadratic')
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                c = app.cEditField.Value;
                app.t = -2*pi:0.1:2*pi;
                app.y = a*app.t.^2+b*app.t+c;
                plot(app.UIAxes,app.t,app.y)
            elseif strcmp(s, 'Exponencial')
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                app.t = 0:0.1:2*pi;
                app.y = a*exp(b*app.t);
                plot(app.UIAxes,app.t,app.y)
            elseif strcmp(s, 'Lineal')
                m = app.aEditField.Value;
                b = app.bEditField.Value;
                app.t = 0:1:10;
                app.y = m*app.t+b;
                plot(app.UIAxes,app.t,app.y)
            elseif strcmp(s, 'Triangular')
                 x = app.aEditField.Value;
                w=app.cEditField.Value;
                a=app.bEditField.Value;
                app.t = -10:1/w:10;
                f= sawtooth(app.t,x);
                app.y=a*f;
                plot(app.UIAxes,app.t,app.y)
                grid on
            elseif strcmp(s, 'Square')
                  d = app.aEditField.Value;
                w=app.bEditField.Value;
                A=app.cEditField.Value;
                app.t = -10:1/w:10;
                f = square(app.t,d);
                app.y=A*f;
                plot(app.UIAxes, app.t,app.y)
                grid on
            elseif strcmp(s, 'Pulse train')
                w = app.aEditField.Value;
                app.t = 0:1/1e3:w;
                d = (0:2:w)';
                x = @rectpuls;
                app.y = pulstran(app.t,d,x);

                plot(app.UIAxes,app.t,app.y)
                hold off

                
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)

        end

        % Callback function
        function signal_2ValueChanged(app, event)

        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            op = app.OperationDropDown.Value;
            
            if strcmp(op, 'Displacement')
                app.dEditFieldLabel.Visible=true;
                app.dEditField.Visible=true;
                app.dEditFieldLabel.Text= 'd';
                app.eEditFieldLabel.Visible=false;
                app.eEditField.Visible=false;
                app.fEditFieldLabel.Visible=false;
                app.fEditField.Visible=false;
                app.Switch.Visible = true;
                app.Switch.Items = {'To the left','To the right'};
                app.Switch_2.Visible=false;
                app.Switch_3.Visible=false;
                app.UIAxes6.Visible=true;
                app.UIAxes2.Visible=false;
                app.UIAxes3.Visible=false;
                app.UIAxes4.Visible=false;
                app.UIAxes5.Visible=false;
            elseif strcmp(op, 'Scalling')
                app.dEditFieldLabel.Visible=true;
                app.dEditField.Visible=true;
                app.dEditFieldLabel.Text='s';
                app.eEditFieldLabel.Visible=false;
                app.eEditField.Visible=false;
                app.fEditFieldLabel.Visible=false;
                app.fEditField.Visible=false;
                app.Switch.Visible = true;
                app.Switch.Items = {'Time scalling','Amplittude scalling'};
                app.Switch_2.Visible=true;
                app.Switch_2.Items = {'Increase', 'Decrease'};
                app.Switch_3.Visible=false;
                app.UIAxes6.Visible=true;
                app.UIAxes2.Visible=false;
                app.UIAxes3.Visible=false;
                app.UIAxes4.Visible=false;
                app.UIAxes5.Visible=false;
            elseif strcmp(op, 'Reflection')
                app.Switch.Visible=false;
                app.Switch_2.Visible=false;
                app.Switch_3.Visible=false;
                app.dEditField.Visible=false;
                app.dEditFieldLabel.Visible=false;
                app.eEditFieldLabel.Visible=false;
                app.eEditField.Visible=false;
                app.fEditFieldLabel.Visible=false;
                app.fEditField.Visible=false;
                app.UIAxes6.Visible=true;
                app.UIAxes2.Visible=false;
                app.UIAxes3.Visible=false;
                app.UIAxes4.Visible=false;
                app.UIAxes5.Visible=false;
            elseif strcmp(op, 'Multiple operations')
                app.Switch.Visible=true;
                app.Switch.Items= {'To the left','To the right'};
                app.Switch_2.Visible=true;
                app.Switch_2.Items = {'Increase [t]', 'Decrease [t]'};
                app.Switch_3.Visible=true;
                app.Switch_3.Items = {'Increase [a]', 'Decrease [a]'};
                app.dEditFieldLabel.Visible=true;
                app.dEditField.Visible=true;
                app.dEditFieldLabel.Text ='d';
                app.eEditFieldLabel.Visible=true;
                app.eEditField.Visible=true;
                app.eEditFieldLabel.Text = 'e';
                app.fEditFieldLabel.Visible=true;
                app.fEditField.Visible=true;

                app.UIAxes2.Visible=true;
                app.UIAxes3.Visible=true;
                app.UIAxes4.Visible=true;
                app.UIAxes5.Visible=true;
                app.UIAxes6.Visible=false;
                

            end
        end

        % Button pushed function: GraphButton_2
        function GraphButton_2Pushed(app, event)
            s = app.OperationDropDown.Value;
            ax = app.UIAxes6;
            ax1 = app.UIAxes2;
            ax2 = app.UIAxes3;
            ax3 = app.UIAxes4;

            if strcmp(s, 'Displacement')
                d = app.dEditField.Value;
                sw = app.Switch.Value;
                    ax.XLim=[-d-5 d+5];
                    ax.YLim=[-d-5 d+5];
                if strcmp(sw, 'To the left')                    
                    for g = 0:d
                    plot(app.UIAxes6,app.t-g,app.y,'r--')
                    pause(0.6)
                    end
                elseif strcmp(sw, 'To the right')
                    ax.XLim=[-d-5 d+5];
                    for g = 0:d
                    plot(app.UIAxes6,app.t+g,app.y,'r--')
                    pause(0.6)
                    end
                end
            elseif strcmp(s, 'Scalling')
                a = app.dEditField.Value;
                sw = app.Switch.Value;
                if strcmp(sw, 'Time scalling')
                    ax.YLim=[-a-5 a+5];
                    ax.XLim=[-a-5 a+5];
                    sw2=app.Switch_2.Value;
                    if strcmp(sw2, 'Increase')
                    for g=1:1:a
                    plot(app.UIAxes6,app.t*g,app.y,'r--')
                    pause(0.6)
                    end
                    elseif strcmp(sw2, 'Decrease')
                    for g=1:1:a 
                    plot(app.UIAxes6,app.t/g,app.y,'r--')
                    pause(0.6)
                    end
                    end
                elseif strcmp(sw, 'Amplittude scalling')
                    a = app.dEditField.Value;
                    sw2=app.Switch_2.Value;
                    ax.YLim=[-a a];
                    ax.XLim=[-a a];
                    
                    if strcmp(sw2, 'Increase')
                    for g= 0.1:a
                        ye=app.y*g;
                        plot(app.UIAxes6,app.t,ye,'r--')
                        pause(0.6)
                    end
                    elseif strcmp(sw2, 'Decrease')
                    for g= 0.1:a
                        app.y=app.y/g;
                        plot(app.UIAxes6,app.t,app.y,'r--')
                        pause(0.6)
                    end
                    end
                end
            elseif strcmp(s,'Reflection')
                plot(app.UIAxes6,app.t*-1,app.y,'r--')
            elseif strcmp(s, 'Multiple operations')
                d = app.dEditField.Value;
                es = app.eEditField.Value;
                a = app.fEditField.Value;
                ax1.XLim = [-10 d+10];
                ax1.YLim = [-10 a+10];
                ax2.XLim = [-10 d+10];
                ax2.YLim = [-10 a+10];
                ax3.XLim = [-10 d+10];
                ax3.YLim = [-10 a+10];
                ax4.XLim = [-10 d+10];
                ax4.YLim = [-10 a+10];

                subplot(2,2,1)
                plot(app.UIAxes2,app.t,app.y)
                for g=1:0.1:d
                    app.t=(app.t)-g;
                    subplot(2,2,2)
                    plot(app.UIAxes3,app.t,app.y,'r--')
                    pause(0.6)
                end
                for g=1:0.1:es
                    t2=app.t*g;
                    subplot(2,2,3)
                    plot(app.UIAxes4,t2,app.y,'r--')
                    pause(0.6)
                end
                for j=1:0.1:a
                    ye=app.y*j;
                    plot(app.UIAxes5,app.t, ye,'r--')
                    pause(0.6)
                end

            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLimitMethod = 'padded';
            app.UIAxes2.YLimitMethod = 'padded';
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes2.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'padded';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Displacement')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Time scalling')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Amplittude scalling')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.Visible = 'off';
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Signal operations')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XLimitMethod = 'padded';
            app.UIAxes6.YLimitMethod = 'padded';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Signal';
            app.signal = uidropdown(app.UIFigure);
            app.signal.Items = {'...', 'Senoidal', 'Pulse', 'Quadratic', 'Exponential', 'Lineal', 'Triangular', 'Square', 'Pulse train'};
            app.signal.ValueChangedFcn = createCallbackFcn(app, @signalValueChanged, true);
            app.signal.Value = '...';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalsgeneratorLabel = uilabel(app.UIFigure);
            app.SignalsgeneratorLabel.Text = 'Signals generator';
            app.SealesySistemasIEN410020223Label = uilabel(app.UIFigure);
            app.SealesySistemasIEN410020223Label.Text = 'Señales y Sistemas IEN 4100. 2022-3';
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'...', 'Displacement', 'Scalling', 'Reflection', 'Multiple operations'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = '...';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'please select the signal to plot';
            app.GraphButton_2 = uibutton(app.UIFigure, 'push');
            app.GraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraphButton_2Pushed, true);
            app.GraphButton_2.Text = 'Graph';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'-', '-'};
            app.Switch_2.Value = '-';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'-', '-'};
            app.Switch.Value = '-';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.Items = {'-', '-'};
            app.Switch_3.Value = '-';
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laboratorio1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end